classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MPEG4UIFigure  matlab.ui.Figure
        Label_2        matlab.ui.control.Label
        Label          matlab.ui.control.Label
        Button_2       matlab.ui.control.Button
        Button         matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function YCbCr_frame = RGB2YCbCr(app,frame)
            [M,N,~] = size(frame);
            YCbCr_frame = zeros(M, N, 3);
            for i = 1:M
                for j = 1:N
                    R = frame(i,j,1);
                    G = frame(i,j,2);
                    B = frame(i,j,3);
                    Y = (0.299*R) + (0.587*G) + (0.114*B);
                    Cb = (-0.168736*R) + (-0.331264*G) + (0.5*B);
                    Cr = (0.5*R) + (-0.418688*G) - (0.081312*B);
                    YCbCr_frame(i,j,1) = Y;
                    YCbCr_frame(i,j,2) = Cb;
                    YCbCr_frame(i,j,3) = Cr;
                end
            end
        end

        function sub_col = sub_color(app,frame)
            sub_col = frame(:,1:2:end);
        end

        function [LL,new_img] = wavelet(app,im,r)
            [M,N] = size(im);
            [L,H] = haar(app,im,1); % row haar transform
            [LL,LH] = haar(app,L,2);% col haar transform
            [HL,HH] = haar(app,H,2);% col haar transform
            new_img = size(M,N);
            new_img(1:M/2,1:N/2) = LL;
            new_img(1:M/2,N/2+1:N) = LH ;
            new_img(M/2+1:M,1:N/2) = HL ;
            new_img(M/2+1:M,N/2+1:N) = HH ;
            [M,N] = size(new_img);
            if r > 1
                for i = 1:r-1
                    new_img = quantize(app,new_img);
                    [LL, new1_img] = wavelet(app,LL, 1);
                    new_img(1:M/2,1:N/2) = new1_img;
                    [M,N] = size(new1_img);
                end
            end
        end

        function [L,H] = haar(app,im,r)
            im = im2double(im);
            [M,N] = size(im);
            H = (1/sqrt(2))*[1 1; -1 1];
            wave = NaN(M,N);

            if r == 1 % row haar transform
                for i = 1:M
                    for j = 1:2:N-1
                        block = im(i,j:j+1);
                        wave(i,j:j+1) = (H * block')';
                    end
                end
                L = wave(:,1:2:end);
                H = wave(:,2:2:end);
            end

            if r == 2 % col haar transform
                for i = 1:2:M-1
                    for j = 1:N
                        block = im(i:i+1,j);
                        wave(i:i+1,j) = H * block;
                    end
                end
                L = wave(1:2:end,:);
                H = wave(2:2:end,:);
            end
        end

        function new_img = quantize(app,img)
            [M,N] = size(img);
            % Округляем значения пикселей до сотых
            new_img = round(img,2);
            LL = new_img(1:M/2,1:N/2);
            LH = new_img(1:M/2,N/2+1:N);
            HL = new_img(M/2+1:M,1:N/2);
            HH = new_img(M/2+1:M,N/2+1:N);
            % Непосредственно квантование
            % LL(LL < 0.05) = 0;
            % LH(LH < 0.5) = 0;
            % HL(HL < 0.5) = 0;
            % HH(HH < 0.2) = 0;
            new_img(1:M/2,1:N/2) = LL;
            new_img(1:M/2,N/2+1:N) = LH;
            new_img(M/2+1:M,1:N/2) = HL;
            new_img(M/2+1:M,N/2+1:N) = HH;
        end

        function [restoredL] = ihaar(app,img1,img2,r)
            [M,N] = size(img1);
            h = (1/sqrt(2))*[1 1; -1 1];

            if r == 1 %row thinning and ihaar
                new_img1 = zeros(M*2,N);
                new_img2 = zeros(M*2,N);
                for i = 1:M
                    new_img1(i*2-1,:) = img1(i,:);
                end
                L = new_img1;
                for i = 1:M
                    new_img2(i*2,:) = img2(i,:);
                end
                H = new_img2;
                restoredL = L+H;
                % row ihaar
                [M,N] = size(restoredL);
                for i = 1:2:M-1
                    for j = 1:N
                        block = restoredL(i:i+1,j);
                        restoredL(i:i+1,j) = (h' * block);
                    end
                end
            end

            if r == 2 %col ihaar
                new_img1 = zeros(M,N*2);
                new_img2 = zeros(M,N*2);
                for i = 1:N
                    new_img1(:,i*2-1) = img1(:,i);
                end
                L = new_img1;
                for i = 1:N
                    new_img2(:,i*2) = img2(:,i);
                end
                H = new_img2;
                restoredL = L+H;
                % row ihaar
                [M,N] = size(restoredL);
                for i = 1:M
                    for j = 1:2:N-1
                        block = restoredL(i,j:j+1);
                        restoredL(i,j:j+1) = (h' * block')';
                    end
                end
            end
        end

        function [img] = iwavelet(app,img,r)
            [M,N] = size(img);
            for i = r:-1:1
                mask = img(1:M/(power(2,i-1)),1:N/(power(2,i-1)));
                [rows,cols] = size(mask);
                LL = mask(1:rows/2,1:cols/2);
                LH = mask(1:rows/2,cols/2+1:cols) ;
                HL = mask(rows/2+1:rows,1:cols/2) ;
                HH = mask(rows/2+1:rows,cols/2+1:cols);
                [restored] = ihaar(app,LL,LH,1);
                [res] = ihaar(app,HL,HH,1);
                [result] = ihaar(app,restored,res,2);
                img(1:M/(power(2,i-1)),1:N/(power(2,i-1))) = result;
            end
        end

        function RGB_frame = YCbCr2RGB(app,frame)
            [M,N,~] = size(frame);
            RGB_frame = zeros(M, N, 3);
            for i = 1:M
                for j = 1:N
                    Y = double(frame(i,j,1));
                    Cb = double(frame(i,j,2));
                    Cr = double(frame(i,j,3));
                    R = Y + (1.402*(Cr));
                    G = Y-(0.34414*(Cb))-(0.71414*(Cr));
                    B = Y+(1.772*(Cb));
                    RGB_frame(i,j,1) = R;
                    RGB_frame(i,j,2) = G;
                    RGB_frame(i,j,3) = B;
                end
            end
        end

        function decoded = irle(app,input)
            decoded = [];
            for i = 1:2:length(input)
                symbol = input(i);
                count = input(i+1);
                decoded = [decoded, repmat(symbol, 1, count)];
            end
        end

        function Output=rle(app,Input)
            L=length(Input);
            j=1;
            k=1;
            i=1;
            while i<2*L
                comp=1;
                for j=j:L
                    if j==L
                        break
                    end
                    if Input(j)==Input(j+1)
                        comp=comp+1;
                    else
                        break
                    end
                end
                Output(k+1)=comp;
                Output(k)=Input(j);
                if j==L && Input(j-1)==Input(j)
                    break
                end
                i=i+1;
                k=k+2;
                j=j+1;
                if j==L
                    % if mod(L,2)==0
                    %     Output(k+1)=1;
                    %     Output(k)=Input(j);
                    % else
                    Output(k+1)=1;
                    Output(k)=Input(j);
                    % end
                    break
                end
            end
        end

        function interp = interpolation(app, frame)
            [M,N] = size(frame);
            interp = imresize(frame,[M,N*2],"lanczos3");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Задаем на какой цвет изменить кнопку при нажатии
            app.Button.BackgroundColor = [0.55,0.95,0.45];

            set(app.Button, 'Enable', 'off');
            set(app.Button_2, 'Enable', 'off');
            file = NaN; path = NaN;
            % [file,path] = uigetfile('*.mp4','Выберите видео');
            while isnan(file)
                [file,path] = uigetfile('*.mp4','Выберите видео');
            end
            full_path = fullfile(path, file);

            vid = VideoReader(full_path);
            M = vid.Height; N = vid.Width;

            numFrames = vid.NumFrames; % Получаем количество кадров в видео

            f = waitbar(0,'Please wait...');
            R = 2;

            tic;
            timerVal = tic;
            for p = 1:numFrames
                frame = read(vid, p); % Считываем текущий кадр
                frame = RGB2YCbCr(app,im2double(frame));
                color(:,:,1) = imresize(frame(:,:,1),[M/2,N/2],"lanczos3");
                color(:,:,2) = round(cat(2,sub_color(app,imresize(frame(:,:,2),[M/2,N/2],"lanczos3")), ...
                sub_color(app,imresize(frame(:,:,3),[M/2,N/2],"lanczos3"))), 2);

                if mod(p,14) == 1

                    frame1 = color;
                    [LL,color(:,:,1)] = wavelet(app,imresize(frame(:,:,1),[M/2,N/2],"lanczos3"),R); % Y
                    clear LL;
                    color1 = color(:);
                    color1 = round(rle(app,color1),2);
                    if p==1
                        [file,path] = uiputfile('*.txt', 'Дайте имя файлу и укажите путь');
                        full_path = fullfile(path, file);
                        writematrix(color1, full_path);
                    else
                        writematrix(color1, full_path,'WriteMode', 'append');
                    end

                else
                    col = color - frame1;
                    color1 = round(rle(app,col(:)),2);
                    frame1 = color;
                    writematrix(color1, full_path, 'WriteMode', 'append');
                end

                f = waitbar(p/numFrames, f, 'Please wait...');
            end

            save('parameters.mat','M',"N","numFrames",'full_path','R');

            zip('test',full_path);

            f = waitbar(1, f, 'Closing...');
            close(f);
            delete(f);

            set(app.Button, 'Enable', 'on');
            set(app.Button_2, 'Enable', 'on');

            % Конец отсчета
            toc;
            % Считаем затраченное время
            elapsedTime = toc(timerVal);
            app.Label_2.Text = num2str(round(elapsedTime),3);

            % Возвращаем исходный цвет
            app.Button.BackgroundColor = [0.58,0.99,0.51];
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % Задаем на какой цвет изменить кнопку при нажатии
            app.Button_2.BackgroundColor = [0.95,0.7,0.45];

            set(app.Button, 'Enable', 'off');
            set(app.Button_2, 'Enable', 'off');

            [file,path] = uigetfile('*.zip', 'Раззипуйте пж');
            full_path = fullfile(path, file);
            unzip(full_path);

            M = load('parameters.mat','M').M/2;
            N = load('parameters.mat','N').N/2;
            numFrames = load('parameters.mat','numFrames').numFrames;
            full_path = load('parameters.mat','full_path').full_path;
            R = load('parameters.mat','R').R;
            [file,path] = uiputfile('*.mp4', 'Дайте имя файлу и укажите путь');
            full_path2 = fullfile(path, file);

            outputVideo = VideoWriter(full_path2, 'MPEG-4');
            outputVideo.FrameRate = 24; % Установка частоты кадров
            open(outputVideo);

            f = waitbar(0,'Please wait...');
            % Запись каждого кадра в видео
            tic;
            timerVal = tic;
            fid = fopen(full_path, 'r'); %
            for p = 1:numFrames

                line = fgetl(fid); % Читаем одну строку из файла
                if line == -1 % Если достигнут конец файла
                    break; % Выходим из цикла
                end

                delim = ',';
                vec = strsplit(line, delim);
                vec = irle(app,str2double(vec));
                if mod(p,14) == 1
                    vector = reshape(vec,[M,N,2]);
                    [vector(:,:,1)] = iwavelet(app,vector(:,:,1),R);
                    tmp = vector;
                    frame(:,:,1) = imresize(vector(:,:,1),[M*2,N*2],"lanczos3");
                    frame(:,:,2) = imresize(interpolation(app,vector(:,1:end/2,2)),[M*2,N*2],"lanczos3");
                    frame(:,:,3) = imresize(interpolation(app,vector(:,end/2+1:end,2)),[M*2,N*2],"lanczos3");
                    fram = YCbCr2RGB(app,frame);
                else
                    tmp = tmp + reshape(vec,[M,N,2]);
                    difference(:,:,1) = imresize(tmp(:,:,1),[M*2,N*2],"lanczos3");
                    difference(:,:,2) = imresize(interpolation(app,tmp(:,1:end/2,2)),[M*2,N*2],"lanczos3");
                    difference(:,:,3) = imresize(interpolation(app,tmp(:,end/2+1:end,2)),[M*2,N*2],"lanczos3");
                    fram = YCbCr2RGB(app,difference);
                end
                writeVideo(outputVideo, im2uint8(imadjust(fram, [0.1 0.9], [0 1])));
                f = waitbar(p/numFrames, f, 'Please wait...');
            end
            fclose(fid); % Закрываем файл
            toc;

            f = waitbar(1, f, 'Closing...');
            close(f);
            delete(f);

            clear RGB_frames;

            % Закрытие объекта VideoWriter
            close(outputVideo);
            clear outputVideo

            set(app.Button, 'Enable', 'on');
            set(app.Button_2, 'Enable', 'on');

            % Конец отсчета
            toc;
            % Считаем затраченное время
            elapsedTime = toc(timerVal);
            app.Label_2.Text =  num2str(round(elapsedTime),3);
            % Возвращаем исходный цвет
            app.Button_2.BackgroundColor = [1.00,0.74,0.50];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MPEG4UIFigure = uifigure('Visible', 'off');
            app.MPEG4UIFigure.Name = 'MPEG-4';
            app.MPEG4UIFigure.Icon = 'MPEG-4.png';
            app.Button = uibutton(app.MPEG4UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Сжать видео';
            app.Button_2 = uibutton(app.MPEG4UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Восстановить видео';
            app.Label = uilabel(app.MPEG4UIFigure);
            app.Label.Text = ' Время выполнения:';
            app.Label_2 = uilabel(app.MPEG4UIFigure);
            app.Label_2.Text = '';
            % Show the figure after all components are created
            app.MPEG4UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MPEG4UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MPEG4UIFigure)
        end
    end
end